classdef interfacedesign < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Channel1ButtonGroup         matlab.ui.container.ButtonGroup
        NotchBandpassButton         matlab.ui.control.ToggleButton
        BandpassButton              matlab.ui.control.ToggleButton
        NotchButton                 matlab.ui.control.ToggleButton
        NeuralinterfaceButtonGroup  matlab.ui.container.ButtonGroup
        Button1_5                   matlab.ui.control.Button
        Button1_4                   matlab.ui.control.Button
        Button1_3                   matlab.ui.control.Button
        Button1_2                   matlab.ui.control.Button
        ViewallButton               matlab.ui.control.Button
        Button2                     matlab.ui.control.Button
        Button1                     matlab.ui.control.Button
        Button_32                   matlab.ui.control.ToggleButton
        Button_31                   matlab.ui.control.ToggleButton
        Button_30                   matlab.ui.control.ToggleButton
        Button_29                   matlab.ui.control.ToggleButton
        Button_28                   matlab.ui.control.ToggleButton
        Button_27                   matlab.ui.control.ToggleButton
        Button_26                   matlab.ui.control.ToggleButton
        Button_25                   matlab.ui.control.ToggleButton
        Button_24                   matlab.ui.control.ToggleButton
        Button_23                   matlab.ui.control.ToggleButton
        Button_22                   matlab.ui.control.ToggleButton
        Button_21                   matlab.ui.control.ToggleButton
        Button_20                   matlab.ui.control.ToggleButton
        Button_19                   matlab.ui.control.ToggleButton
        Button_18                   matlab.ui.control.ToggleButton
        Button_17                   matlab.ui.control.ToggleButton
        Button_16                   matlab.ui.control.ToggleButton
        Button_15                   matlab.ui.control.ToggleButton
        Button_14                   matlab.ui.control.ToggleButton
        Button_13                   matlab.ui.control.ToggleButton
        Button_12                   matlab.ui.control.ToggleButton
        Button_11                   matlab.ui.control.ToggleButton
        Button_10                   matlab.ui.control.ToggleButton
        Button_9                    matlab.ui.control.ToggleButton
        Button_7                    matlab.ui.control.ToggleButton
        Button_6                    matlab.ui.control.ToggleButton
        Button_4                    matlab.ui.control.ToggleButton
        Button_3                    matlab.ui.control.ToggleButton
        Button_2                    matlab.ui.control.ToggleButton
        Image                       matlab.ui.control.Image
        Button                      matlab.ui.control.ToggleButton
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %t = evalin('base', 't');
            %amplifier_data = evalin('base', 'amplifier_data');
            %f_notch = evalin('base', 'f_notch');
            %fs = evalin ('base','fs' );
       
        end

        % Button pushed function: Button1
        function Button1Pushed(app, event)
            channel = 1;
            t = evalin('base', 't');
            amplifier_data = evalin('base', 'amplifier_data');
            f_notch = evalin('base', 'f_notch');
            fs = evalin ('base','fs' );
            notch (t, amplifier_data, f_notch, fs, channel);
        
            
        end

        % Button pushed function: Button2
        function Button2Pushed(app, event)
            channel = 2;
            notch (t, amplifier_data, f_notch, fs, channel);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NeuralinterfaceButtonGroup = uibuttongroup(app.UIFigure);
            app.NeuralinterfaceButtonGroup.Title = 'Neural interface';
            app.Button = uitogglebutton(app.NeuralinterfaceButtonGroup);
            app.Button.Text = '0';
            app.Button.Value = true;
            app.Image = uiimage(app.NeuralinterfaceButtonGroup);
            app.Image.ImageSource = 'Neural interface zoom.png';
            app.Button_2 = uitogglebutton(app.NeuralinterfaceButtonGroup);
            app.Button_2.Text = '28';
            app.Button_3 = uitogglebutton(app.NeuralinterfaceButtonGroup);
            app.Button_3.Text = '31';
            app.Button_4 = uitogglebutton(app.NeuralinterfaceButtonGroup);
            app.Button_4.Text = '3';
            app.Button_6 = uitogglebutton(app.NeuralinterfaceButtonGroup);
            app.Button_6.Text = '29';
            app.Button_7 = uitogglebutton(app.NeuralinterfaceButtonGroup);
            app.Button_7.Text = '30';
            app.Button_9 = uitogglebutton(app.NeuralinterfaceButtonGroup);
            app.Button_9.Text = '26';
            app.Button_10 = uitogglebutton(app.NeuralinterfaceButtonGroup);
            app.Button_10.Text = '25';
            app.Button_11 = uitogglebutton(app.NeuralinterfaceButtonGroup);
            app.Button_11.Text = '27';
            app.Button_12 = uitogglebutton(app.NeuralinterfaceButtonGroup);
            app.Button_12.Text = '24';
            app.Button_13 = uitogglebutton(app.NeuralinterfaceButtonGroup);
            app.Button_13.Text = '8';
            app.Button_14 = uitogglebutton(app.NeuralinterfaceButtonGroup);
            app.Button_14.Text = '4';
            app.Button_15 = uitogglebutton(app.NeuralinterfaceButtonGroup);
            app.Button_15.Text = '7';
            app.Button_16 = uitogglebutton(app.NeuralinterfaceButtonGroup);
            app.Button_16.Text = '5';
            app.Button_17 = uitogglebutton(app.NeuralinterfaceButtonGroup);
            app.Button_17.Text = '6';
            app.Button_18 = uitogglebutton(app.NeuralinterfaceButtonGroup);
            app.Button_18.Text = '17';
            app.Button_19 = uitogglebutton(app.NeuralinterfaceButtonGroup);
            app.Button_19.Text = '15';
            app.Button_20 = uitogglebutton(app.NeuralinterfaceButtonGroup);
            app.Button_20.Text = '16';
            app.Button_21 = uitogglebutton(app.NeuralinterfaceButtonGroup);
            app.Button_21.Text = '14';
            app.Button_22 = uitogglebutton(app.NeuralinterfaceButtonGroup);
            app.Button_22.Text = '18';
            app.Button_23 = uitogglebutton(app.NeuralinterfaceButtonGroup);
            app.Button_23.Text = '23';
            app.Button_24 = uitogglebutton(app.NeuralinterfaceButtonGroup);
            app.Button_24.Text = '13';
            app.Button_25 = uitogglebutton(app.NeuralinterfaceButtonGroup);
            app.Button_25.Text = '19';
            app.Button_26 = uitogglebutton(app.NeuralinterfaceButtonGroup);
            app.Button_26.Text = '9';
            app.Button_27 = uitogglebutton(app.NeuralinterfaceButtonGroup);
            app.Button_27.Text = '12';
            app.Button_28 = uitogglebutton(app.NeuralinterfaceButtonGroup);
            app.Button_28.Text = '20';
            app.Button_29 = uitogglebutton(app.NeuralinterfaceButtonGroup);
            app.Button_29.Text = '22';
            app.Button_30 = uitogglebutton(app.NeuralinterfaceButtonGroup);
            app.Button_30.Text = '11';
            app.Button_31 = uitogglebutton(app.NeuralinterfaceButtonGroup);
            app.Button_31.Text = '21';
            app.Button_32 = uitogglebutton(app.NeuralinterfaceButtonGroup);
            app.Button_32.Text = '32';
            app.Button1 = uibutton(app.NeuralinterfaceButtonGroup, 'push');
            app.Button1.ButtonPushedFcn = createCallbackFcn(app, @Button1Pushed, true);
            app.Button1.Text = '1';
            app.Button2 = uibutton(app.NeuralinterfaceButtonGroup, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @Button2Pushed, true);
            app.Button2.Text = '2';
            app.ViewallButton = uibutton(app.NeuralinterfaceButtonGroup, 'push');
            app.ViewallButton.Text = 'View all';
            app.Button1_2 = uibutton(app.NeuralinterfaceButtonGroup, 'push');
            app.Button1_2.Text = '1 - 8';
            app.Button1_3 = uibutton(app.NeuralinterfaceButtonGroup, 'push');
            app.Button1_3.Text = '9 - 16';
            app.Button1_4 = uibutton(app.NeuralinterfaceButtonGroup, 'push');
            app.Button1_4.Text = '17 - 24';
            app.Button1_5 = uibutton(app.NeuralinterfaceButtonGroup, 'push');
            app.Button1_5.Text = '25 -32';
            app.Channel1ButtonGroup = uibuttongroup(app.UIFigure);
            app.Channel1ButtonGroup.Title = 'Channel 1';
            app.NotchButton = uitogglebutton(app.Channel1ButtonGroup);
            app.NotchButton.Text = 'Notch';
            app.NotchButton.Value = true;
            app.BandpassButton = uitogglebutton(app.Channel1ButtonGroup);
            app.BandpassButton.Text = 'Bandpass';
            app.NotchBandpassButton = uitogglebutton(app.Channel1ButtonGroup);
            app.NotchBandpassButton.Text = 'Notch + Bandpass';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interfacedesign

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end